classdef app_Simulink_interfaz_juntos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        SliderLabel    matlab.ui.control.Label
        Slider         matlab.ui.control.Slider
        IniciarButton  matlab.ui.control.Button
        PararButton    matlab.ui.control.Button
        CerrarButton   matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            %Función para realizar iniciar la simulacion
            Nombre_Archivo_simulink='Esquema.slx';
            %Buscar archivode SIMULINK en el directorio
            find_system('Name',Nombre_Archivo_simulink);
            %Inicia la simulacion
            set_param(gcs,'SimulationCommand','Start');
        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            %Pausarla simulacion actual
            set_param(gcs,'SimulationCommand','Pause');
            Pause(0.5)
            %Reanuda la simulación
            set_param(gcs,'SimulationCommand','Continue');
            Pause(0.2)
            %Para la simulacion
             set_param(gcs,'SimulationCommand','Stop');
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            %Funcion para cerrar el archivo de SIMULINK
            Nombre_Archivo_simulink='Esquema.slx';
            set_param(gcs,'SimulationCommand','Stop');
            save_system(Nombre_Archivo_simulink);
            close_system(Nombre_Archivo_simulink);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            %Función para editar valores de los objetos de SIMULINK
            changingValue = event.Value;
            %Ruta/Objeto,parametros a asignar, donde num2str convierte de 
            %formato número a string
            %Set_param permite asignar un valor a un objeto
             set_param('Esquema/a',...
                 'value',num2str(changingValue));
             set_param('Esquema/b',...
                 'Gain',num2str(changingValue*2));
             %get_param determina que funciones tiene disponibles el objeto
             disp(get_param('Esquema/a','objectparameters'))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.PararButton = uibutton(app.UIFigure, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = {'Parar'; ''};
            app.CerrarButton = uibutton(app.UIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_Simulink_interfaz_juntos

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end